classdef RoboticsAppGUIV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometryInputsTab              matlab.ui.container.Tab
        DebugTestButton                matlab.ui.control.Button
        CurrentRobotDetailTextArea     matlab.ui.control.TextArea
        CurrentRobotDetailTextAreaLabel  matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        NoteAvoidinitialRobotPositionatsingularityLabel  matlab.ui.control.Label
        WarningchangingvaluewillclearalljointdataLabel  matlab.ui.control.Label
        StatusNoRobotProvidedLabel     matlab.ui.control.Label
        GenerateRobotButton            matlab.ui.control.Button
        MZEditField                    matlab.ui.control.NumericEditField
        MZEditFieldLabel               matlab.ui.control.Label
        MYEditField                    matlab.ui.control.NumericEditField
        MYEditFieldLabel               matlab.ui.control.Label
        MXEditField                    matlab.ui.control.NumericEditField
        MXEditFieldLabel               matlab.ui.control.Label
        FZEditField                    matlab.ui.control.NumericEditField
        FZEditFieldLabel               matlab.ui.control.Label
        FYEditField                    matlab.ui.control.NumericEditField
        FYEditFieldLabel               matlab.ui.control.Label
        FXEditField                    matlab.ui.control.NumericEditField
        FXEditFieldLabel               matlab.ui.control.Label
        EndEffectorForceComponentsinendeffectorframeLabel  matlab.ui.control.Label
        ForceInputsLabel               matlab.ui.control.Label
        PleaseSelectJointPanel         matlab.ui.container.Panel
        OtherParametersPanel           matlab.ui.container.Panel
        InitialJointPositionEditField  matlab.ui.control.NumericEditField
        InitialJointPositionEditFieldLabel  matlab.ui.control.Label
        JointMinEditField              matlab.ui.control.NumericEditField
        JointMinEditFieldLabel         matlab.ui.control.Label
        JointMaxEditField              matlab.ui.control.NumericEditField
        JointMaxEditFieldLabel         matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        CoulombFrictionCoeffEditField  matlab.ui.control.NumericEditField
        CoulombFrictionCoeffLabel      matlab.ui.control.Label
        ViscousFrictionCoefEditField   matlab.ui.control.NumericEditField
        ViscousFrictionCoefEditFieldLabel  matlab.ui.control.Label
        Kr_iEditField                  matlab.ui.control.NumericEditField
        Kr_iEditFieldLabel             matlab.ui.control.Label
        MassPropertiesPanel            matlab.ui.container.Panel
        MotorPropertiesPanel           matlab.ui.container.Panel
        I_33EditField_2                matlab.ui.control.NumericEditField
        I_33EditField_2Label           matlab.ui.control.Label
        I_32EditField_2                matlab.ui.control.NumericEditField
        I_32EditField_2Label           matlab.ui.control.Label
        I_31EditField_2                matlab.ui.control.NumericEditField
        I_31EditField_2Label           matlab.ui.control.Label
        I_23EditField_2                matlab.ui.control.NumericEditField
        I_23EditField_2Label           matlab.ui.control.Label
        I_22EditField_2                matlab.ui.control.NumericEditField
        I_22EditField_2Label           matlab.ui.control.Label
        I_21EditField_2                matlab.ui.control.NumericEditField
        I_21EditField_2Label           matlab.ui.control.Label
        I_13EditField_2                matlab.ui.control.NumericEditField
        I_13EditField_2Label           matlab.ui.control.Label
        I_12EditField_2                matlab.ui.control.NumericEditField
        I_12EditField_2Label           matlab.ui.control.Label
        I_11EditField_2                matlab.ui.control.NumericEditField
        I_11EditField_2Label           matlab.ui.control.Label
        MassEditField_2                matlab.ui.control.NumericEditField
        MassEditField_2Label           matlab.ui.control.Label
        LinkPropertiesPanel            matlab.ui.container.Panel
        I_33EditField                  matlab.ui.control.NumericEditField
        I_33EditFieldLabel             matlab.ui.control.Label
        I_32EditField                  matlab.ui.control.NumericEditField
        I_32EditFieldLabel             matlab.ui.control.Label
        I_31EditField                  matlab.ui.control.NumericEditField
        I_31EditFieldLabel             matlab.ui.control.Label
        I_23EditField                  matlab.ui.control.NumericEditField
        I_23EditFieldLabel             matlab.ui.control.Label
        I_22EditField                  matlab.ui.control.NumericEditField
        I_22EditFieldLabel             matlab.ui.control.Label
        I_21EditField                  matlab.ui.control.NumericEditField
        I_21EditFieldLabel             matlab.ui.control.Label
        I_13EditField                  matlab.ui.control.NumericEditField
        I_13EditFieldLabel             matlab.ui.control.Label
        I_12EditField                  matlab.ui.control.NumericEditField
        I_12EditFieldLabel             matlab.ui.control.Label
        I_11EditField                  matlab.ui.control.NumericEditField
        I_11EditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        DHParametersPanel              matlab.ui.container.Panel
        dEditField                     matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        SelectedJointDropDown          matlab.ui.control.DropDown
        SelectedJointLabel             matlab.ui.control.Label
        NumberofJointsSpinner          matlab.ui.control.Spinner
        NumberofJointsSpinnerLabel     matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        GravityComponentsLabel         matlab.ui.control.Label
        GeometryInputsLabel            matlab.ui.control.Label
        EoMsTab                        matlab.ui.container.Tab
        GenerateAnalyticalEquationsofMotionButton  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        JointDynamicsTab               matlab.ui.container.Tab
        TrajectoryErrorLable           matlab.ui.control.Label
        DynamicsGeneratorErrorLable    matlab.ui.control.Label
        GenerateJointDyanamicsButton   matlab.ui.control.Button
        TrajectoriesasafunctionoftimePanel  matlab.ui.container.Panel
        NotePleaseprovideposeintheZYZschemeLabel  matlab.ui.control.Label
        ActivatePsiCheckBox            matlab.ui.control.CheckBox
        ActivatePhiCheckBox            matlab.ui.control.CheckBox
        ActivateThetaCheckBox          matlab.ui.control.CheckBox
        ActivateZCheckBox              matlab.ui.control.CheckBox
        ActivateYCheckBox              matlab.ui.control.CheckBox
        ActivateXCheckBox              matlab.ui.control.CheckBox
        TimeStepSizeEditField          matlab.ui.control.NumericEditField
        TimeStepSizeEditFieldLabel     matlab.ui.control.Label
        FinalTimeEditField             matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel        matlab.ui.control.Label
        PsitEditField                  matlab.ui.control.EditField
        PsitEditFieldLabel             matlab.ui.control.Label
        PhitEditField                  matlab.ui.control.EditField
        PhitEditFieldLabel             matlab.ui.control.Label
        ThetatEditField                matlab.ui.control.EditField
        ThetatEditFieldLabel           matlab.ui.control.Label
        ZtEditField                    matlab.ui.control.EditField
        ZtEditFieldLabel               matlab.ui.control.Label
        YtEditField                    matlab.ui.control.EditField
        YtEditFieldLabel               matlab.ui.control.Label
        XtEditField                    matlab.ui.control.EditField
        XtEditFieldLabel               matlab.ui.control.Label
        JointDyanamicsLabel            matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ForceControlTab                matlab.ui.container.Tab
        HelpTab                        matlab.ui.container.Tab
        Hyperlink                      matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
    %Define all public variables here

        NumJoints = 1; % number of joints
        GravityVector = [0, 0, -9.81]; %gravity vector defined in base frame

        %DH parameters and other joint variables --> i represents joint num
        alpha_i = [];
        theta_i = [];
        a_i = [];
        d_i = [];

        JointType = {}; %prismatic or revolute
        kr_i = []; %gear ratio
        ViscousFrictionCoeff_i = [];
        CoulombFrictionCoeff_i = [];
        I_L_i = []; %moment of inertia for link
        I_m_i = []; %moment of inertia for link
        MotorMass_i = [];
        LinkMass_i = [];
        JointParameterMin_i= [];
        JointParameterMax_i= [];
        q_0_i = []; %initial Joint parameter value

        %indicates which joint is currently active to be edited in the
        %inputs table
        SelectedJoint;
        
        %joint force vector in end effector frame
        F_ex = zeros(6,1);
        ForcesInBaseFrame = true;% variable for switch showing if the force is defined in the base frame

        ManipulatorSerialLinkObject;
        
        %complete analytical Equation
        FullAnalyticalEquation;
    end
    
    methods (Access = private)
        
        function UpdateShownJointParameterFields(app)
            %load previously entered values
            app.AlphaEditField.Value = (app.alpha_i(str2num(app.SelectedJoint)));
            app.ThetaEditField.Value = (app.theta_i(str2num(app.SelectedJoint)));
            app.aEditField.Value= (app.a_i(str2num(app.SelectedJoint)));
            app.dEditField.Value= (app.d_i(str2num(app.SelectedJoint)));
            app.JointMaxEditField.Value= (app.JointParameterMax_i(str2num(app.SelectedJoint)));
            app.JointMinEditField.Value= (app.JointParameterMin_i(str2num(app.SelectedJoint)));
            app.ViscousFrictionCoefEditField.Value = app.CoulombFrictionCoeff_i(str2num(app.SelectedJoint));
            app.CoulombFrictionCoeffEditField.Value= app.ViscousFrictionCoeff_i(str2num(app.SelectedJoint));
            app.Kr_iEditField.Value = app.kr_i(str2num(app.SelectedJoint));
            app.MassEditField.Value = app.LinkMass_i(str2num(app.SelectedJoint));
            app.MassEditField_2.Value= app.MotorMass_i(str2num(app.SelectedJoint));
    
            %link moment of inertia fields:
            app.I_11EditField.Value = app.I_L_i(1,1,str2num(app.SelectedJoint));
            app.I_12EditField.Value = app.I_L_i(1,2,str2num(app.SelectedJoint));
            app.I_13EditField.Value = app.I_L_i(1,3,str2num(app.SelectedJoint));
            app.I_21EditField.Value = app.I_L_i(2,1,str2num(app.SelectedJoint));
            app.I_22EditField.Value = app.I_L_i(2,2,str2num(app.SelectedJoint));
            app.I_23EditField.Value = app.I_L_i(2,3,str2num(app.SelectedJoint));
            app.I_31EditField.Value = app.I_L_i(3,1,str2num(app.SelectedJoint));
            app.I_32EditField.Value = app.I_L_i(3,2,str2num(app.SelectedJoint));
            app.I_33EditField.Value = app.I_L_i(3,3,str2num(app.SelectedJoint));
    
            %motor moment of inertia fields:
            app.I_11EditField_2.Value = app.I_m_i(1,1,str2num(app.SelectedJoint));
            app.I_12EditField_2.Value = app.I_m_i(1,2,str2num(app.SelectedJoint));
            app.I_13EditField_2.Value = app.I_m_i(1,3,str2num(app.SelectedJoint));
            app.I_21EditField_2.Value = app.I_m_i(2,1,str2num(app.SelectedJoint));
            app.I_22EditField_2.Value = app.I_m_i(2,2,str2num(app.SelectedJoint));
            app.I_23EditField_2.Value = app.I_m_i(2,3,str2num(app.SelectedJoint));
            app.I_31EditField_2.Value = app.I_m_i(3,1,str2num(app.SelectedJoint));  
            app.I_32EditField_2.Value = app.I_m_i(3,2,str2num(app.SelectedJoint));
            app.I_33EditField_2.Value = app.I_m_i(3,3,str2num(app.SelectedJoint));
            app.InitialJointPositionEditField.Value = app.q_0_i(str2num(app.SelectedJoint));

            %set switch state for joint type
            if strcmp(app.JointType(str2num(app.SelectedJoint)),"Revolute")
                app.Switch_2.Value = 'Revolute';
            else
                app.Switch_2.Value = 'Prismatic';
            end
        end
        
    end
    
    methods (Access = public)
        
        function [robot] = make_robot(app, DH, OPTIONS)
            %MAKE_ROBOT Constructs a SerialLink Object
            %   Provide the DH parameters and options for kinematics and dynamics
            %   DH Input
            %       DH = [d theta a alpha];
            %       d, theta, a, alpha are nx1 arrays
            %       DH is an nx4 matrix
            % 
            %   OPTIONS
            %   see SerialLink documentation for details about input format
            %
                % Validate Input
            dhdims = size(DH)';
            if(dhdims(2) ~= 4)
                ME = MException('ikin:typeError', ...
                    'DH Parameters are in incorrect format. Expected nx5 Matrix');
                throw(ME)
            end
        
            numLinks = dhdims(1);
            fprintf('\nNumber of Links: %d', numLinks)
        
            % Create Links
            L = cell(numLinks, 1);
            for i = 1:numLinks
                % Collect Inputs
                dh = DH(i,:);
                O = OPTIONS.offset(i);
                qlim = OPTIONS.qlims{i};
                isRevolute = OPTIONS.isRevolute(i);
                I = OPTIONS.inertia{i};
                r = OPTIONS.centerOfMass{i};
                m = OPTIONS.mass(i);
                G = OPTIONS.gearRatio(i);
                B = OPTIONS.jointFric(i);
                Jm = OPTIONS.motorInertia(i);
                Tc = OPTIONS.coulombFric{i};
        
                % Create Link Object
                if(isRevolute)
        %             fprintf('\n\tLink %d is Revolute', i);
                    L{i} = Revolute('d', dh(1), 'a', dh(3), 'alpha', dh(4), 'offset', O, 'I', I, 'r', r, 'm', m, 'G', G, 'B', B, 'Jm', Jm, 'Tc', Tc);
                else
        %             fprintf('\n\tLink %d is Prismatic', i);
                    L{i} = Prismatic('theta', dh(2), 'a', dh(3), 'alpha', dh(4), 'offset', O, 'qlim', qlim, 'I', I, 'r', r, 'm', m, 'G', G, 'B', B, 'Jm', Jm, 'Tc', Tc);
                end
        %         Display link dynamics and kinematics
        %         L{i}.dyn
                end
            
                % Create Serial Robot
                R = SerialLink(horzcat(L{:}));
            
                % Create workspace
                SIZE = OPTIONS.workspace;
                R.name = 'robot';
                R.plotopt = {'workspace' [-SIZE,SIZE,-SIZE,SIZE,-SIZE,SIZE]};
                R.gravity = OPTIONS.gravity;
            
                robot = R;
        end
        
        function R = EulerAnglesRotationMatrix(app, EulerAnglesName, Angle1, Angle2, Angle3)
            if(strcmpi(EulerAnglesName, 'zyz'))
                R = ElementaryRoatation(app,"z", Angle1)*ElementaryRoatation(app,"y", Angle2)*ElementaryRoatation(app,"z", Angle3);
            elseif(strcmpi(EulerAnglesName, 'rpy'))
                R = ElementaryRoatation(app,"z", Angle3)*ElementaryRoatation(app,"y", Angle2)*ElementaryRoatation(app,"x", Angle1);
            else
                error("Euler angles name not recognized")
            end
         end

        function R = ElementaryRoatation(app, AxisName, Angle)
            if(strcmpi(AxisName, 'x'))
                R = [1 0 0; 0 cos(Angle) -sin(Angle); 0 sin(Angle) cos(Angle)];
            elseif(strcmpi(AxisName, 'y'))
                R = [cos(Angle) 0 sin(Angle); 0 1 0; -sin(Angle) 0 cos(Angle)];
            elseif(strcmpi(AxisName, 'z'))
                R = [cos(Angle) -sin(Angle), 0; sin(Angle) cos(Angle), 0; 0 0 1];
            else
                error("Please enter valid Axis Name")
            end
        end

        function [FullEquation] = GenerateAnalyticalEquations(app, numJoints,g_unitVector, JointTypes, dh_params)

        %JointTypes--> 0 == revolute, 1== prismatic
        
        %lets generate some symbolic symbols some parameters
            syms g
            
            q =sym('q', [numJoints,1]);
            ddq =sym('ddq', [numJoints,1]);
            dq =sym('dq', [numJoints,1]);
            I_Lzz_i =sym("I_Lzz_i", [3,3,numJoints]);
            I_mzz_i =sym("I_mzz_i", [3,3,numJoints]);
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
            Fv_diags =sym("Fv", [numJoints,1]);
            Fv = diag(Fv_diags);
            Fs_diags =sym("Fs", [numJoints,1]);
            Fs= diag(Fs_diags);
            tau =sym("tau", [numJoints,1]);
            h_e =sym("h_e", [6,1]);
        
            g_o = g*g_unitVector;%gravity vector
        
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
        %declaring some vectors. Each column if for a different i indice
        %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
        %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
        %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end
        
        %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
        %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
        %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)
        
        
        %calculate Arm Geometric Jacobian which takes us to the end effector
            for i = 1:numJoints
                if(JointTypes(i) ==0)  
                    %Revolute Case:
                    J_i{i} = [cross(z(:,i), (T_o_i(1:3,4) - P_i(:,i)));z(:,i)];
                  
                else
                    %prismatic case
                    J_i{i} = [z(:,i); 0;0;0];
                end
            end
        
        %combine all jacobian colunmns
            J = [J_i{:}];
        
        %calculate B
            B = zeros(numJoints);
            for i = 1:numJoints
                
                R_Li_i =cell2sym(R_Li(i));
                R_mi_i =cell2sym(R_mi(i));
            
                Jp_li_i = cell2sym(Jp_Li(i));
                Jo_li_i = cell2sym(Jo_Li(i));
                Jp_mi_i = cell2sym(Jp_mi(i));
                Jo_mi_i = cell2sym(Jo_mi(i));
            
                B= B + ml(i)*(Jp_li_i.')*Jp_li_i +Jo_li_i.'* R_Li_i*I_Lzz_i(:,:,i)*(R_Li_i.')*Jo_li_i + mm(i)*(Jp_mi_i.')*Jp_mi_i + Jo_mi_i.'*R_mi_i*I_mzz_i(:,:,i)* (R_mi_i.')*Jo_mi_i;
            end
            
            B = simplify(B);
            %matlabFunction(Beq,"File","Bfunction") %create function with B
        
        %calculate C_ijk
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C_ijk(i,j,k) = (1/2)*(diff(B(i,j),q(k)) + diff(B(i,k),q(j)) + diff(B(j,k),q(i)));
                        C_ijk(i,j,k)=simplify((C_ijk(i,j,k)));
                    end
                end
            end
        
        %calculate C_ij
            C = repmat({0},numJoints,numJoints);
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C(i,j) = sym2cell(cell2sym(C(i,j)) + C_ijk(i,j,k)*dq(k));
                    end
                end
            end
            
            C=simplify(cell2sym(C));
            C_ijk=simplify((C_ijk));
        
        
        %calculate g_i
            g_i=repmat({0}, numJoints,1);
            for i =1:numJoints
                for j=1:numJoints
                    Jp_li_j = cell2sym(Jp_Li(i));
                    Jp_mi_j = cell2sym(Jp_mi(i));
                   
                    g_i{i} =  g_i{i} - (ml(j)*g_o.'*Jp_li_j(:,j) + mm(j)*g_o.'*Jp_mi_j(:,j)) ;
                end
            end
            
            g_i = simplify(cell2sym(g_i));
        
        %build the full dynamics questions
            FullEquation = tau - (J.')*h_e == B*ddq+C*dq+Fv*dq+Fs*sign(dq)+g_i;
            
            FullEquation=simplify(FullEquation);
        
        
        end
    
        function [B] = Generate_B(app, q_inp)
            %pretty messy and backward, but I am going to find the
            %analytical eq for B and then just sub in everything to get B

            
           numJoints = app.NumJoints;
           %g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
           q =sym('q', [app.NumJoints,1]);
           Symbolic_a =sym('a', [app.NumJoints,1]);
           Symbolic_d =sym('d', [app.NumJoints,1]);
           Symbolic_theta =sym('theta', [app.NumJoints,1]);

           JointTypes = ones(1, app.NumJoints);
           for i = 1:app.NumJoints
                %set joint type
                if(strcmp(app.JointType(i),"Revolute"))
                    JointTypes(i) = 0;
                    Symbolic_theta(i) = q(i);
                else
                    Symbolic_d(i) = q(i);
                end

                if(app.a_i ==0)
                    Symbolic_a(i) = 0;
                end

                  if((app.d_i == 0) & (JointTypes(i) == 0 ))
                    Symbolic_d(i) = 0;
                  end

                if((app.theta_i) == 0 & (JointTypes(i) == 1))
                    Symbolic_theta(i) = 0;
                end
           end


           dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
           %JointTypes--> 0 == revolute, 1== prismatic
            
        %lets generate some symbolic symbols some parameters

            I_Lzz_i =sym("I_Lzz_i", [3,3,numJoints]);
            I_mzz_i =sym("I_mzz_i", [3,3,numJoints]);
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
           
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
        %declaring some vectors. Each column if for a different i indice
        %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
        %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
        %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end
        
        %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
        %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
        %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)
        
        
        %calculate Arm Geometric Jacobian which takes us to the end effector
            for i = 1:numJoints
                if(JointTypes(i) ==0)  
                    %Revolute Case:
                    J_i{i} = [cross(z(:,i), (T_o_i(1:3,4) - P_i(:,i)));z(:,i)];
                  
                else
                    %prismatic case
                    J_i{i} = [z(:,i); 0;0;0];
                end
            end
        
        %combine all jacobian colunmns
            J = [J_i{:}];
        
        %calculate B
            B = zeros(numJoints);
            for i = 1:numJoints
                
                R_Li_i =cell2sym(R_Li(i));
                R_mi_i =cell2sym(R_mi(i));
            
                Jp_li_i = cell2sym(Jp_Li(i));
                Jo_li_i = cell2sym(Jo_Li(i));
                Jp_mi_i = cell2sym(Jp_mi(i));
                Jo_mi_i = cell2sym(Jo_mi(i));

                B= B + ml(i)*(Jp_li_i.')*Jp_li_i +Jo_li_i.'* R_Li_i*I_Lzz_i(:,:,i)*(R_Li_i.')*Jo_li_i + mm(i)*(Jp_mi_i.')*Jp_mi_i + Jo_mi_i.'*R_mi_i*I_mzz_i(:,:,i)* (R_mi_i.')*Jo_mi_i;
            end
            
            B = simplify(B);
        
            B = subs(B, [q,Kr,mm,ml,a,alpha,d,theta],[q_inp,app.kr_i,app.MotorMass_i,app.LinkMass_i,app.a_i,app.alpha_i, app.d_i,app.theta_i]);
            B = subs(B,I_mzz_i, app.I_m_i);
            B = subs(B,I_Lzz_i, app.I_L_i);
        end

        function [n] = Generate_n(app, q_inp,q_dot_inp)
           numJoints = app.NumJoints;
           g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
           q =sym('q', [app.NumJoints,1]);
           Symbolic_a =sym('a', [app.NumJoints,1]);
           Symbolic_d =sym('d', [app.NumJoints,1]);
           Symbolic_theta =sym('theta', [app.NumJoints,1]);

           JointTypes = ones(1, app.NumJoints);
           for i = 1:app.NumJoints
                %set joint type
                if(strcmp(app.JointType(i),"Revolute"))
                    JointTypes(i) = 0;
                    Symbolic_theta(i) = q(i);
                else
                    Symbolic_d(i) = q(i);
                end

                if(app.a_i ==0)
                    Symbolic_a(i) = 0;
                end

                  if((app.d_i == 0) & (JointTypes(i) == 0 ))
                    Symbolic_d(i) = 0;
                  end

                if((app.theta_i) == 0 & (JointTypes(i) == 1))
                    Symbolic_theta(i) = 0;
                end
           end


           dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
           %JointTypes--> 0 == revolute, 1== prismatic
        
        %lets generate some symbolic symbols some parameters
            syms g
            
            dq =sym('dq', [numJoints,1]);
            I_Lzz_i =sym("I_Lzz_i", [3,3,numJoints]);
            I_mzz_i =sym("I_mzz_i", [3,3,numJoints]);
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
            Fv_diags =sym("Fv", [numJoints,1]);
            Fv = diag(Fv_diags);
            Fs_diags =sym("Fs", [numJoints,1]);
            Fs= diag(Fs_diags);
            
            g_o = g*g_unitVector;%gravity vector
        
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
        %declaring some vectors. Each column if for a different i indice
        %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
        %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
        %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end
        
        %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
        %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
        %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)
        
        
        %calculate Arm Geometric Jacobian which takes us to the end effector
            for i = 1:numJoints
                if(JointTypes(i) ==0)  
                    %Revolute Case:
                    J_i{i} = [cross(z(:,i), (T_o_i(1:3,4) - P_i(:,i)));z(:,i)];
                  
                else
                    %prismatic case
                    J_i{i} = [z(:,i); 0;0;0];
                end
            end
        
        %combine all jacobian colunmns
            J = [J_i{:}];
        
        %calculate B
            B = zeros(numJoints);
            for i = 1:numJoints
                
                R_Li_i =cell2sym(R_Li(i));
                R_mi_i =cell2sym(R_mi(i));
            
                Jp_li_i = cell2sym(Jp_Li(i));
                Jo_li_i = cell2sym(Jo_Li(i));
                Jp_mi_i = cell2sym(Jp_mi(i));
                Jo_mi_i = cell2sym(Jo_mi(i));
            
                B= B + ml(i)*(Jp_li_i.')*Jp_li_i +Jo_li_i.'* R_Li_i*I_Lzz_i(:,:,i)*(R_Li_i.')*Jo_li_i + mm(i)*(Jp_mi_i.')*Jp_mi_i + Jo_mi_i.'*R_mi_i*I_mzz_i(:,:,i)* (R_mi_i.')*Jo_mi_i;
            end
            
            B = simplify(B);
            %matlabFunction(Beq,"File","Bfunction") %create function with B
        
        %calculate C_ijk
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C_ijk(i,j,k) = (1/2)*(diff(B(i,j),q(k)) + diff(B(i,k),q(j)) + diff(B(j,k),q(i)));
                        C_ijk(i,j,k)=simplify((C_ijk(i,j,k)));
                    end
                end
            end
        
        %calculate C_ij
            C = repmat({0},numJoints,numJoints);
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C(i,j) = sym2cell(cell2sym(C(i,j)) + C_ijk(i,j,k)*dq(k));
                    end
                end
            end
            
            C=simplify(cell2sym(C));
            C_ijk=simplify((C_ijk));
        
        
        %calculate g_i
            g_i=repmat({0}, numJoints,1);
            for i =1:numJoints
                for j=1:numJoints
                    Jp_li_j = cell2sym(Jp_Li(i));
                    Jp_mi_j = cell2sym(Jp_mi(i));
                   
                    g_i{i} =  g_i{i} - (ml(j)*g_o.'*Jp_li_j(:,j) + mm(j)*g_o.'*Jp_mi_j(:,j)) ;
                end
            end
            
            g_i = simplify(cell2sym(g_i));


            n = C*dq+Fv*dq+Fs*sign(dq)+g_i;

            n = subs(n, [q, dq, Kr,mm,ml,Fv_diags,Fs_diags,a,alpha,d,theta],[q_inp, q_dot_inp, app.kr_i, app.MotorMass_i, app.LinkMass_i, app.ViscousFrictionCoeff_i, app.CoulombFrictionCoeff_i, app.a_i, app.alpha_i, app.d_i, app.theta_i]);
            n = subs(n,I_mzz_i, app.I_m_i);
            n = subs(n,I_Lzz_i, app.I_L_i);
            n = subs(n,g,norm(app.GravityVector));
        end

        function [G] = Generate_G(app, q_inp)
          %pretty messy and backward, but I am going to find the
            %analytical eq for G and then just sub in everything to get G

            
           numJoints = app.NumJoints;
           g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
           q =sym('q', [app.NumJoints,1]);
           Symbolic_a =sym('a', [app.NumJoints,1]);
           Symbolic_d =sym('d', [app.NumJoints,1]);
           Symbolic_theta =sym('theta', [app.NumJoints,1]);

           JointTypes = ones(1, app.NumJoints);
           for i = 1:app.NumJoints
                %set joint type
                if(strcmp(app.JointType(i),"Revolute"))
                    JointTypes(i) = 0;
                    Symbolic_theta(i) = q(i);
                else
                    Symbolic_d(i) = q(i);
                end

                if(app.a_i ==0)
                    Symbolic_a(i) = 0;
                end

                  if((app.d_i == 0) & (JointTypes(i) == 0 ))
                    Symbolic_d(i) = 0;
                  end

                if((app.theta_i) == 0 & (JointTypes(i) == 1))
                    Symbolic_theta(i) = 0;
                end
           end


           dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
           %JointTypes--> 0 == revolute, 1== prismatic
        
            %lets generate some symbolic symbols some parameters
            syms g
            
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
        
            g_o = g*g_unitVector;%gravity vector
        
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
            %declaring some vectors. Each column if for a different i indice
            %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
            %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
            %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end
        
            %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
            %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
            %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)


            %calculate g_i
            g_i=repmat({0}, numJoints,1);
            for i =1:numJoints
                for j=1:numJoints
                    Jp_li_j = cell2sym(Jp_Li(i));
                    Jp_mi_j = cell2sym(Jp_mi(i));
                   
                    g_i{i} =  g_i{i} - (ml(j)*g_o.'*Jp_li_j(:,j) + mm(j)*g_o.'*Jp_mi_j(:,j)) ;
                end
            end
            
            g_i = simplify(cell2sym(g_i));

            G = subs(g_i,[q,Kr,mm,ml,a,alpha,d,theta],[q_inp, app.kr_i,app.MotorMass_i, app.LinkMass_i,app.a_i, app.alpha_i, app.d_i, app.theta_i]);
            G = subs(g_i,g,norm(app.GravityVector));
        end

        function [Xe] = forwardKinematics(app, q)
            %calculates forward kinematics
            Xe = app.ManipulatorSerialLinkObject.fkine(q);
        end
        
        function [Ja] = GenerateAnlyticalJacobian(app, q)
            %returns analytical jacobian in terms of RPY angles
            Ja = app.ManipulatorSerialLinkObject.jacob0(q,'rpy');
        end

        function [J] = GenerateGeometricJacobian(app, q)
            %returns geomtric jacobian
            J = app.ManipulatorSerialLinkObject.jacob0(q);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: NumberofJointsSpinner
        function NumberofJointsSpinnerValueChanged(app, event)
            value = app.NumberofJointsSpinner.Value;

            %set the number of joints
            app.NumJoints = value;

            %have drop down selecting joint show all joint options
            app.SelectedJointDropDown.Items = string(1:app.NumJoints);
            
            %reset selected joint
            app.SelectedJoint = "1"; 

            %set length of all joint parameters
            for k = 1:app.NumJoints
                
                app.JointType(k) = {'Revolute'};
            end

            app.alpha_i = zeros(app.NumJoints, 1);
            app.theta_i =  zeros(app.NumJoints, 1);
            app.a_i =  zeros(app.NumJoints, 1);
            app.d_i =  zeros(app.NumJoints, 1);
            app.kr_i = ones(app.NumJoints, 1);
        
            app.ViscousFrictionCoeff_i = zeros(app.NumJoints, 1);
            app.CoulombFrictionCoeff_i = zeros(app.NumJoints, 1);
            app.MotorMass_i = ones(app.NumJoints, 1);
            app.LinkMass_i = ones(app.NumJoints, 1);
            app.I_L_i = zeros(3, 3, app.NumJoints); %(row, col, i)
            app.I_m_i = zeros(3, 3, app.NumJoints); %(row, col, i)
            
            for i = 1:app.NumJoints
                app.I_L_i(:,:,i) = eye(3);
                app.I_m_i(:,:,i) = eye(3);
            end

            app.JointParameterMin_i= zeros(app.NumJoints, 1);
            app.JointParameterMax_i= zeros(app.NumJoints, 1);
            app.q_0_i = ones(app.NumJoints, 1)*2*pi;
            
            %update panel showing which joint is selected
            app.PleaseSelectJointPanel.Title = strcat('Joint Number:', num2str(app.SelectedJoint));

            %update shown joint parameters 
            UpdateShownJointParameterFields(app);
        end

        % Value changed function: SelectedJointDropDown
        function SelectedJointDropDownValueChanged(app, event)
            value = app.SelectedJointDropDown.Value;

            %update selected joint
            app.SelectedJoint = value; 
          
            %update panel showing which joint is selected
            app.PleaseSelectJointPanel.Title = strcat('Joint Number:', num2str(value));
            
            %load previously entered values
            UpdateShownJointParameterFields(app);
        
        end

        % Value changed function: ViscousFrictionCoefEditField
        function ViscousFrictionCoefEditFieldValueChanged(app, event)
            value = app.ViscousFrictionCoefEditField.Value;
            app.CoulombFrictionCoeff_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: CoulombFrictionCoeffEditField
        function CoulombFrictionCoeffEditFieldValueChanged(app, event)
            value = app.CoulombFrictionCoeffEditField.Value;
            app.ViscousFrictionCoeff_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: Kr_iEditField
        function Kr_iEditFieldValueChanged(app, event)
            value = app.Kr_iEditField.Value;
            app.kr_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.GravityVector(1) = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.GravityVector(2) = value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.GravityVector(3) = value;
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            %link mass
            value = app.MassEditField.Value;
            app.LinkMass_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: MassEditField_2
        function MassEditField_2ValueChanged(app, event)
            %motor mass
            value = app.MassEditField_2.Value;
            app.MotorMass_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_11EditField
        function I_11EditFieldValueChanged(app, event)
            value = app.I_11EditField.Value;
            app.I_L_i(1,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_12EditField
        function I_12EditFieldValueChanged(app, event)
            value = app.I_12EditField.Value;
            app.I_L_i(1,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_13EditField
        function I_13EditFieldValueChanged(app, event)
            value = app.I_13EditField.Value;
            app.I_L_i(1,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_21EditField
        function I_21EditFieldValueChanged(app, event)
            value = app.I_21EditField.Value;
            app.I_L_i(2,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_22EditField
        function I_22EditFieldValueChanged(app, event)
            value = app.I_22EditField.Value;
            app.I_L_i(2,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_23EditField
        function I_23EditFieldValueChanged(app, event)
            value = app.I_23EditField.Value;
            app.I_L_i(2,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_31EditField
        function I_31EditFieldValueChanged(app, event)
            value = app.I_31EditField.Value;
            app.I_L_i(3,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_32EditField
        function I_32EditFieldValueChanged(app, event)
            value = app.I_32EditField.Value;
            app.I_L_i(3,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_33EditField
        function I_33EditFieldValueChanged(app, event)
            value = app.I_33EditField.Value;
            app.I_L_i(3,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_11EditField_2
        function I_11EditField_2ValueChanged(app, event)
            value = app.I_11EditField_2.Value;
            app.I_m_i(1,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_12EditField_2
        function I_12EditField_2ValueChanged(app, event)
            value = app.I_12EditField_2.Value;
            app.I_m_i(1,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_13EditField_2
        function I_13EditField_2ValueChanged(app, event)
            value = app.I_13EditField_2.Value;
            app.I_m_i(1,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_21EditField_2
        function I_21EditField_2ValueChanged(app, event)
            value = app.I_21EditField_2.Value;
            app.I_m_i(2,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_22EditField_2
        function I_22EditField_2ValueChanged(app, event)
            value = app.I_22EditField_2.Value;
            app.I_m_i(2,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_23EditField_2
        function I_23EditField_2ValueChanged(app, event)
            value = app.I_23EditField_2.Value;
            app.I_m_i(2,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_31EditField_2
        function I_31EditField_2ValueChanged(app, event)
            value = app.I_31EditField_2.Value;
            app.I_m_i(3,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_32EditField_2
        function I_32EditField_2ValueChanged(app, event)
            value = app.I_32EditField_2.Value;
            app.I_m_i(3,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_33EditField_2
        function I_33EditField_2ValueChanged(app, event)
            value = app.I_33EditField_2.Value;
            app.I_m_i(3,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: FXEditField
        function FXEditFieldValueChanged(app, event)
            value = app.FXEditField.Value;
            app.F_ex(1) = value;
        end

        % Value changed function: FYEditField
        function FYEditFieldValueChanged(app, event)
            value = app.FYEditField.Value;
            app.F_ex(2) = value;
        end

        % Value changed function: FZEditField
        function FZEditFieldValueChanged(app, event)
            value = app.FZEditField.Value;
            app.F_ex(3) = value;
        end

        % Value changed function: MXEditField
        function MXEditFieldValueChanged(app, event)
            value = app.MXEditField.Value;
            app.F_ex(4) = value;
        end

        % Value changed function: MYEditField
        function MYEditFieldValueChanged(app, event)
            value = app.MYEditField.Value;
            app.F_ex(5) = value;
        end

        % Value changed function: MZEditField
        function MZEditFieldValueChanged(app, event)
            value = app.MZEditField.Value;
            app.F_ex(6) = value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if(strcmp(value,"Revolute"))
                app.JointType(str2num(app.SelectedJoint)) = {'Revolute'};
            else
                app.JointType(str2num(app.SelectedJoint)) = {'Prismatic'};
            end
        end

        % Button pushed function: GenerateRobotButton
        function GenerateRobotButtonPushed(app, event)
             %try
                app.StatusNoRobotProvidedLabel.Text = "Status: Updating...";
                
                %nx1 -- DH(i,:) = [d theta a alpha]
                DH = [app.d_i app.theta_i app.a_i app.alpha_i];
                
                % Robot Options
                OPTIONS.numLinks    = app.NumJoints;
                OPTIONS.workspace   = max(max(app.a_i),max(app.d_i))*3;     % Workspace Size
                OPTIONS.gravity     = app.GravityVector;   % Gravity Vector
    
                % Declare some Link Options
                qlims   = cell(OPTIONS.numLinks, 1);
                I       = cell(OPTIONS.numLinks, 1);
                I_m     = cell(OPTIONS.numLinks, 1);
                COM     = cell(OPTIONS.numLinks, 1);
                TC      = cell(OPTIONS.numLinks, 1);
                JointTypeVector = zeros(1, OPTIONS.numLinks);
    
                for i=1:OPTIONS.numLinks
    
                    %set joint type
                    if(strcmp(app.JointType(i),"Revolute"))
                        JointTypeVector(i) =1;
                    end
    
                    qlims{i} = [app.JointParameterMin_i(i) app.JointParameterMax_i(i)];
                    I{i} = app.I_L_i(:,:,i);
                    I_m{i} =app.I_m_i(:,:,i);   
                    COM{i} = zeros(3, 1);
                    TC{i} = ones(2, 1)*app.CoulombFrictionCoeff_i(i);
    
                end
                
                OPTIONS.isRevolute  = JointTypeVector;          % Link Type (1=revolute, 0=prismatic)
                OPTIONS.offset      = zeros(OPTIONS.numLinks);  % Joint Param. Offset --???
                OPTIONS.qlims       = qlims;                    % Joint Param. Limits
                OPTIONS.inertia     = I;                        % Inertia Matrix
                OPTIONS.mass        = app.MotorMass_i;          % Mass
                OPTIONS.centerOfMass= COM;                      % Center of Mass --???
                OPTIONS.jointFric   = app.ViscousFrictionCoeff_i;  % Joint Friction
                OPTIONS.coulombFric = TC;                       % Coulomb/Static Friction --???
                OPTIONS.gearRatio   = app.kr_i;                 % Gear Ratio
                OPTIONS.motorInertia= I_m;                      % Motor Inertia --???
                
                % END ROBOT SPECIFICATION %
            
                app.ManipulatorSerialLinkObject = make_robot(app, DH, OPTIONS);
                app.StatusNoRobotProvidedLabel.Text = "Status: Robot Provided";
                
                figure(1)
                clf
                app.ManipulatorSerialLinkObject.plot(app.q_0_i.');

                app.CurrentRobotDetailTextArea.Value = cellstr( app.ManipulatorSerialLinkObject.char);

            % catch
            %      app.StatusNoRobotProvidedLabel.Text = "Status: Error in Provided Robot";
            % end

            
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditField.Value;
            app.alpha_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            value = app.ThetaEditField.Value;
            app.theta_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.a_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            app.d_i(str2num(app.SelectedJoint)) = value;
        end

        % Button pushed function: GenerateAnalyticalEquationsofMotionButton
        function GenerateAnalyticalEquationsofMotionButtonPushed(app, event)
          try
               app.TextArea.Value = "Loading...";
               %generate g_unitVector
               g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
                
               Symbolic_q =sym('q', [app.NumJoints,1]);
               Symbolic_a =sym('a', [app.NumJoints,1]);
               Symbolic_d =sym('d', [app.NumJoints,1]);
               Symbolic_theta =sym('theta', [app.NumJoints,1]);
    
               JointTypeVector = ones(1, app.NumJoints);
               for i = 1:app.NumJoints
    
                    %set joint type
                    if(strcmp(app.JointType(i),"Revolute"))
                        JointTypeVector(i) = 0;
                        Symbolic_theta(i) = Symbolic_q(i);
                    else
                        Symbolic_d(i) = Symbolic_q(i);
                    end
    
                    if(app.a_i ==0)
                        Symbolic_a(i) = 0;
                    end
    
                      if((app.d_i ==0) & (JointTypeVector(i) == 0 ))
                        Symbolic_d(i) = 0;
                      end
    
                    if((app.theta_i) ==0 & (JointTypeVector(i) == 1))
                        Symbolic_theta(i) = 0;
                    end
               end
    
    
                dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
    
                app.FullAnalyticalEquation = GenerateAnalyticalEquations(app, app.NumJoints, g_unitVector, JointTypeVector, dh_params);
                
                for i= 1:app.NumJoints
                      EquationString(i)= {strcat('Equation #', num2str(i), ':',32,char( app.FullAnalyticalEquation(i)),10)};
                end
                
                app.TextArea.Value = EquationString;
          catch
                app.TextArea.Value = "Error in trying to generate Analytical Equations. Please check inputs.";
          end

        end

        % Button pushed function: GenerateJointDyanamicsButton
        function GenerateJointDyanamicsButtonPushed(app, event)
             try

                syms t

                %get time step information and initialize trajectory matrix
                timeSteps = 0: app.TimeStepSizeEditField.Value :app.FinalTimeEditField.Value;
                TrajectoryTransMatrix = zeros(4,4,length(timeSteps)); %homogeneous transformation matrix

                % initialized function handles for angles so that if we
                % dont use the angles, the R matrix is just the eye
                x_func=@(t) 0*t;
                y_func=@(t) 0*t;
                z_func=@(t) 0*t;
                phi_func=@(t) 0*t;
                theta_func=@(t) 0*t;
                psi_func=@(t) 0*t;


                %find which Dimensions we will be specifying with
                %trajectory equations--m is a mask vector (1 x 6)
                % which specifies the Cartesian DOF (in the wrist 
                % coordinate frame) that will be ignored
                m = zeros(1,6);
                if(app.ActivateXCheckBox.Value ==1)
                    m(1) = 1;
                end
                if(app.ActivateYCheckBox.Value ==1)
                    m(2) = 1;
                end
                if(app.ActivateZCheckBox.Value ==1)
                    m(3) = 1;
                end
                if(app.ActivatePhiCheckBox.Value ==1)
                    m(4) = 1;
                end
                if(app.ActivateThetaCheckBox.Value ==1)
                    m(5) = 1;
                end
                if(app.ActivatePsiCheckBox.Value ==1)
                    m(6) = 1;
                end

                try
                    %convert equation input to syms and then into functions
                    if(m(1) == 1)
                        xeq_sym = str2sym(app.XtEditField.Value);
                        x_func = matlabFunction(xeq_sym,"Vars",t);
                    end
        
                    if(m(2) == 1)
                        yeq_sym = str2sym(app.YtEditField.Value);
                        y_func = matlabFunction(yeq_sym,"Vars",t);
                    end
        
                    if(m(3) == 1)
                        zeq_sym = str2sym(app.ZtEditField.Value);
                        z_func = matlabFunction(zeq_sym,"Vars",t);
                    end
        
                    if(m(5) == 1)
                        thetaeq_sym = str2sym(app.ThetatEditField.Value);
                        theta_func = matlabFunction(thetaeq_sym,"Vars",t);
                    end
        
                    if(m(4) == 1)
                        phieq_sym = str2sym(app.PhitEditField.Value);
                        phi_func = matlabFunction(phieq_sym,"Vars",t);
                    end
        
                    if(m(6) == 1)
                        psieq_sym = str2sym(app.PsitEditField.Value);
                        psi_func = matlabFunction(psieq_sym,"Vars",t);
                    end

                    app.TrajectoryErrorLable.Text =" ";
                catch
                    app.TrajectoryErrorLable.Text ="Error in Trajectory";
                end
    
                
                %build transformation matrices for each time step
                for i= 1:length(timeSteps)
    
                    R = EulerAnglesRotationMatrix(app, 'zyz', phi_func(timeSteps(i)), theta_func(timeSteps(i)), psi_func(timeSteps(i)));
    
                    TrajectoryTransMatrix(1:3,1:3,i) = R;
                    TrajectoryTransMatrix(1,4,i) = x_func(timeSteps(i));
                    TrajectoryTransMatrix(2,4,i) = y_func(timeSteps(i));
                    TrajectoryTransMatrix(3,4,i) = z_func(timeSteps(i));
                    TrajectoryTransMatrix(4,4,i) = 1;
                end
            
                %calculate the joint variables using ikine
                q_Trajectory= app.ManipulatorSerialLinkObject.ikine(TrajectoryTransMatrix,'q0',app.q_0_i.', 'mask', m);
                
                q_Trajectory = rad2deg(q_Trajectory);
                q_Trajectory = wrapTo360(q_Trajectory);

                %numerically differentiate to get dq and ddq
                dq_Trajectory = diff(q_Trajectory)/app.TimeStepSizeEditField.Value;
                ddq_Trajectory = diff(dq_Trajectory)/app.TimeStepSizeEditField.Value;

                %lets check how our inverse kinematics did
                ForwardKinematicCheck = app.ManipulatorSerialLinkObject.fkine(deg2rad(q_Trajectory));
                x_exact = zeros(1,length(timeSteps));
                y_exact = zeros(1,length(timeSteps));
                z_exact = zeros(1,length(timeSteps));

                for i =1:length(timeSteps)
                    x_exact(i) = ForwardKinematicCheck(i).t(1);
                    y_exact(i) = ForwardKinematicCheck(i).t(2);
                    z_exact(i) = ForwardKinematicCheck(i).t(3);
                end

                %plot it all up
                legendText={};
                for i = 1:app.NumJoints
                    legendText{i} = ['Joint #',num2str(i)];
                end

                plot3(app.UIAxes2, x_func(timeSteps(:)),y_func(timeSteps(:)),z_func(timeSteps(:)),x_exact,y_exact,z_exact )
                
                legend(app.UIAxes2, {'Desired Path', 'Actual Path'}, 'Location','best')
         

                plot(app.UIAxes,timeSteps,q_Trajectory);
                legend(app.UIAxes, legendText, 'Location','best')
                plot(app.UIAxes_2,timeSteps(1:length(dq_Trajectory)),dq_Trajectory);
                legend(app.UIAxes_2, legendText, 'Location','best')
                plot(app.UIAxes_3,timeSteps(1:length(ddq_Trajectory)),ddq_Trajectory);
                legend(app.UIAxes_3, legendText, 'Location','best')

                app.DynamicsGeneratorErrorLable.Text = "";

                app.ManipulatorSerialLinkObject.animate(deg2rad(q_Trajectory))
            catch
                app.DynamicsGeneratorErrorLable.Text = "Error: Please Verify Robot and Trajectory are Correct";
            end

        end

        % Value changed function: JointMaxEditField
        function JointMaxEditFieldValueChanged(app, event)
            value = app.JointMaxEditField.Value;
            app.JointParameterMax_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: JointMinEditField
        function JointMinEditFieldValueChanged(app, event)
            value = app.JointMinEditField.Value;
            app.JointParameterMin_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: InitialJointPositionEditField
        function InitialJointPositionEditFieldValueChanged(app, event)
            value = app.InitialJointPositionEditField.Value;
            app.q_0_i(str2num(app.SelectedJoint)) = value;
        end

        % Button pushed function: DebugTestButton
        function DebugTestButtonPushed(app, event)
            [q,dq] = manipulatorDynamics(app,  ones([app.NumJoints,1]),  ones([app.NumJoints,1]),  ones([app.NumJoints,1]), .1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometryInputsTab = uitab(app.TabGroup);
            app.GeometryInputsTab.Title = 'Geometry Inputs';
            app.GeometryInputsLabel = uilabel(app.GeometryInputsTab);
            app.GeometryInputsLabel.Text = 'Geometry Inputs';
            app.GravityComponentsLabel = uilabel(app.GeometryInputsTab);
            app.GravityComponentsLabel.Text = 'Gravity Components';
            app.XLabel = uilabel(app.GeometryInputsTab);
            app.XLabel.Text = 'X:';
            app.XEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YLabel = uilabel(app.GeometryInputsTab);
            app.YLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZLabel = uilabel(app.GeometryInputsTab);
            app.ZLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = -9.81;
            app.NumberofJointsSpinnerLabel = uilabel(app.GeometryInputsTab);
            app.NumberofJointsSpinnerLabel.Text = 'Number of Joints ';
            app.NumberofJointsSpinner = uispinner(app.GeometryInputsTab);
            app.NumberofJointsSpinner.Limits = [1 Inf];
            app.NumberofJointsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofJointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofJointsSpinnerValueChanged, true);
            app.NumberofJointsSpinner.Value = 1;
            app.SelectedJointLabel = uilabel(app.GeometryInputsTab);
            app.SelectedJointLabel.Text = 'Selected Joint: ';
            app.SelectedJointDropDown = uidropdown(app.GeometryInputsTab);
            app.SelectedJointDropDown.Items = {};
            app.SelectedJointDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedJointDropDownValueChanged, true);
            app.SelectedJointDropDown.Value = {};
            app.PleaseSelectJointPanel = uipanel(app.GeometryInputsTab);
            app.PleaseSelectJointPanel.Title = 'Please Select Joint';
            app.DHParametersPanel = uipanel(app.PleaseSelectJointPanel);
            app.DHParametersPanel.Title = 'DH Parameters';
            app.AlphaEditFieldLabel = uilabel(app.DHParametersPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.ThetaEditFieldLabel = uilabel(app.DHParametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.aEditFieldLabel = uilabel(app.DHParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.DHParametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.MassPropertiesPanel = uipanel(app.PleaseSelectJointPanel);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.LinkPropertiesPanel = uipanel(app.MassPropertiesPanel);
            app.LinkPropertiesPanel.Title = 'Link Properties';
            app.MassEditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.MassEditField.Value = 1;
            app.I_11EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_11EditFieldLabel.Text = 'I_11';
            app.I_11EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_11EditField.ValueChangedFcn = createCallbackFcn(app, @I_11EditFieldValueChanged, true);
            app.I_11EditField.Value = 1;
            app.I_12EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_12EditFieldLabel.Text = 'I_12';
            app.I_12EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_12EditField.ValueChangedFcn = createCallbackFcn(app, @I_12EditFieldValueChanged, true);
            app.I_13EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_13EditFieldLabel.Text = 'I_13';
            app.I_13EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_13EditField.ValueChangedFcn = createCallbackFcn(app, @I_13EditFieldValueChanged, true);
            app.I_21EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_21EditFieldLabel.Text = 'I_21';
            app.I_21EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_21EditField.ValueChangedFcn = createCallbackFcn(app, @I_21EditFieldValueChanged, true);
            app.I_22EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_22EditFieldLabel.Text = 'I_22';
            app.I_22EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_22EditField.ValueChangedFcn = createCallbackFcn(app, @I_22EditFieldValueChanged, true);
            app.I_22EditField.Value = 1;
            app.I_23EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_23EditFieldLabel.Text = 'I_23';
            app.I_23EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_23EditField.ValueChangedFcn = createCallbackFcn(app, @I_23EditFieldValueChanged, true);
            app.I_31EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_31EditFieldLabel.Text = 'I_31';
            app.I_31EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_31EditField.ValueChangedFcn = createCallbackFcn(app, @I_31EditFieldValueChanged, true);
            app.I_32EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_32EditFieldLabel.Text = 'I_32';
            app.I_32EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_32EditField.ValueChangedFcn = createCallbackFcn(app, @I_32EditFieldValueChanged, true);
            app.I_33EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_33EditFieldLabel.Text = 'I_33';
            app.I_33EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_33EditField.ValueChangedFcn = createCallbackFcn(app, @I_33EditFieldValueChanged, true);
            app.I_33EditField.Value = 1;
            app.MotorPropertiesPanel = uipanel(app.MassPropertiesPanel);
            app.MotorPropertiesPanel.Title = 'Motor Properties';
            app.MassEditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.MassEditField_2Label.Text = 'Mass';
            app.MassEditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.MassEditField_2.ValueChangedFcn = createCallbackFcn(app, @MassEditField_2ValueChanged, true);
            app.MassEditField_2.Value = 1;
            app.I_11EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_11EditField_2Label.Text = 'I_11';
            app.I_11EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_11EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_11EditField_2ValueChanged, true);
            app.I_11EditField_2.Value = 1;
            app.I_12EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_12EditField_2Label.Text = 'I_12';
            app.I_12EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_12EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_12EditField_2ValueChanged, true);
            app.I_13EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_13EditField_2Label.Text = 'I_13';
            app.I_13EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_13EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_13EditField_2ValueChanged, true);
            app.I_21EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_21EditField_2Label.Text = 'I_21';
            app.I_21EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_21EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_21EditField_2ValueChanged, true);
            app.I_22EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_22EditField_2Label.Text = 'I_22';
            app.I_22EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_22EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_22EditField_2ValueChanged, true);
            app.I_22EditField_2.Value = 1;
            app.I_23EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_23EditField_2Label.Text = 'I_23';
            app.I_23EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_23EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_23EditField_2ValueChanged, true);
            app.I_31EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_31EditField_2Label.Text = 'I_31';
            app.I_31EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_31EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_31EditField_2ValueChanged, true);
            app.I_32EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_32EditField_2Label.Text = 'I_32';
            app.I_32EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_32EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_32EditField_2ValueChanged, true);
            app.I_33EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_33EditField_2Label.Text = 'I_33';
            app.I_33EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_33EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_33EditField_2ValueChanged, true);
            app.I_33EditField_2.Value = 1;
            app.OtherParametersPanel = uipanel(app.PleaseSelectJointPanel);
            app.OtherParametersPanel.Title = 'Other Parameters';
            app.Kr_iEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.Kr_iEditFieldLabel.Text = 'Kr_i';
            app.Kr_iEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.Kr_iEditField.ValueChangedFcn = createCallbackFcn(app, @Kr_iEditFieldValueChanged, true);
            app.Kr_iEditField.Value = 1;
            app.ViscousFrictionCoefEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.ViscousFrictionCoefEditFieldLabel.Text = 'Viscous Friction Coef';
            app.ViscousFrictionCoefEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.ViscousFrictionCoefEditField.ValueChangedFcn = createCallbackFcn(app, @ViscousFrictionCoefEditFieldValueChanged, true);
            app.CoulombFrictionCoeffLabel = uilabel(app.OtherParametersPanel);
            app.CoulombFrictionCoeffLabel.Text = 'Coulomb Friction Coeff.';
            app.CoulombFrictionCoeffEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.CoulombFrictionCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @CoulombFrictionCoeffEditFieldValueChanged, true);
            app.Switch_2 = uiswitch(app.OtherParametersPanel, 'slider');
            app.Switch_2.Items = {'Revolute', 'Prismatic'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Revolute';
            app.JointMaxEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.JointMaxEditFieldLabel.Text = 'Joint Max';
            app.JointMaxEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.JointMaxEditField.ValueChangedFcn = createCallbackFcn(app, @JointMaxEditFieldValueChanged, true);
            app.JointMaxEditField.Value = 1;
            app.JointMinEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.JointMinEditFieldLabel.Text = 'Joint Min';
            app.JointMinEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.JointMinEditField.ValueChangedFcn = createCallbackFcn(app, @JointMinEditFieldValueChanged, true);
            app.InitialJointPositionEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.InitialJointPositionEditFieldLabel.Text = 'Initial Joint Position';
            app.InitialJointPositionEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.InitialJointPositionEditField.ValueChangedFcn = createCallbackFcn(app, @InitialJointPositionEditFieldValueChanged, true);
            app.ForceInputsLabel = uilabel(app.GeometryInputsTab);
            app.ForceInputsLabel.Text = 'Force Inputs';
            app.EndEffectorForceComponentsinendeffectorframeLabel = uilabel(app.GeometryInputsTab);
            app.EndEffectorForceComponentsinendeffectorframeLabel.Text = 'End Effector Force Components (in end effector frame)';
            app.FXEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.FXEditFieldLabel.Text = 'FX:';
            app.FXEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.FXEditField.ValueChangedFcn = createCallbackFcn(app, @FXEditFieldValueChanged, true);
            app.FYEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.FYEditFieldLabel.Text = 'FY:';
            app.FYEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.FYEditField.ValueChangedFcn = createCallbackFcn(app, @FYEditFieldValueChanged, true);
            app.FZEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.FZEditFieldLabel.Text = 'FZ:';
            app.FZEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.FZEditField.ValueChangedFcn = createCallbackFcn(app, @FZEditFieldValueChanged, true);
            app.MXEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.MXEditFieldLabel.Text = 'MX:';
            app.MXEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.MXEditField.ValueChangedFcn = createCallbackFcn(app, @MXEditFieldValueChanged, true);
            app.MYEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.MYEditFieldLabel.Text = 'MY:';
            app.MYEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.MYEditField.ValueChangedFcn = createCallbackFcn(app, @MYEditFieldValueChanged, true);
            app.MZEditFieldLabel = uilabel(app.GeometryInputsTab);
            app.MZEditFieldLabel.Text = 'MZ:';
            app.MZEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.MZEditField.ValueChangedFcn = createCallbackFcn(app, @MZEditFieldValueChanged, true);
            app.GenerateRobotButton = uibutton(app.GeometryInputsTab, 'push');
            app.GenerateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRobotButtonPushed, true);
            app.GenerateRobotButton.Text = 'Generate Robot';
            app.StatusNoRobotProvidedLabel = uilabel(app.GeometryInputsTab);
            app.StatusNoRobotProvidedLabel.Text = 'Status: No Robot Provided';
            app.WarningchangingvaluewillclearalljointdataLabel = uilabel(app.GeometryInputsTab);
            app.WarningchangingvaluewillclearalljointdataLabel.Text = 'Warning: changing value will clear all joint data';
            app.NoteAvoidinitialRobotPositionatsingularityLabel = uilabel(app.GeometryInputsTab);
            app.NoteAvoidinitialRobotPositionatsingularityLabel.Text = 'Note: Avoid initial Robot Position at singularity';
            app.TextArea_2 = uitextarea(app.GeometryInputsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Notes:'; '- Avoid initial Robot Position at singularity'; ''; ''};
            app.CurrentRobotDetailTextAreaLabel = uilabel(app.GeometryInputsTab);
            app.CurrentRobotDetailTextAreaLabel.Text = 'Current Robot Detail';
            app.CurrentRobotDetailTextArea = uitextarea(app.GeometryInputsTab);
            app.CurrentRobotDetailTextArea.Value = {'Robot Parameters will appear here after clicking "Generate Robot"'};
            app.DebugTestButton = uibutton(app.GeometryInputsTab, 'push');
            app.DebugTestButton.ButtonPushedFcn = createCallbackFcn(app, @DebugTestButtonPushed, true);
            app.DebugTestButton.Text = 'Debug Test';
            app.EoMsTab = uitab(app.TabGroup);
            app.EoMsTab.Title = 'EoMs';
            app.TextArea = uitextarea(app.EoMsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please enter a valid robot arm in "Geometry Inputs".'; 'Then when you are ready click "Generate Analytical Equations of Motion". This will generate the 2nd order ODEs for the robotic arm in symbolic terms!'};
            app.GenerateAnalyticalEquationsofMotionButton = uibutton(app.EoMsTab, 'push');
            app.GenerateAnalyticalEquationsofMotionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnalyticalEquationsofMotionButtonPushed, true);
            app.GenerateAnalyticalEquationsofMotionButton.Text = 'Generate Analytical Equations of Motion';
            app.JointDynamicsTab = uitab(app.TabGroup);
            app.JointDynamicsTab.Title = 'Joint Dynamics ';
            app.UIAxes = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes, 'q(t)')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'q')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes_2, 'q_{dot}(t)')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'q_{dot}')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes_3, 'q_{ddot}(t)')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'q_{ddot}')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes2, 'Desired Path')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.JointDyanamicsLabel = uilabel(app.JointDynamicsTab);
            app.JointDyanamicsLabel.Text = 'Joint Dyanamics ';
            app.TrajectoriesasafunctionoftimePanel = uipanel(app.JointDynamicsTab);
            app.TrajectoriesasafunctionoftimePanel.Title = 'Trajectories as a function of time: ';
            app.XtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.XtEditField.Value = 'cos(3.14*t)';
            app.YtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.YtEditField.Value = 'sin(3.14*t)';
            app.ZtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.ZtEditFieldLabel.Text = 'Z(t)';
            app.ZtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.ZtEditField.Value = 'E.g.: sin(3.14*t)';
            app.ThetatEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.ThetatEditFieldLabel.Text = 'Theta(t)';
            app.ThetatEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.ThetatEditField.Value = 'Please enter an equation';
            app.PhitEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.PhitEditFieldLabel.Text = 'Phi(t)';
            app.PhitEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.PhitEditField.Value = 'Please enter an equation';
            app.PsitEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.PsitEditFieldLabel.Text = 'Psi(t)';
            app.PsitEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.PsitEditField.Value = 'Please enter an equation';
            app.FinalTimeEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'numeric');
            app.FinalTimeEditField.Value = 1;
            app.TimeStepSizeEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.TimeStepSizeEditFieldLabel.Text = 'Time Step Size';
            app.TimeStepSizeEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'numeric');
            app.TimeStepSizeEditField.Value = 0.01;
            app.ActivateXCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateXCheckBox.Text = 'Activate X';
            app.ActivateYCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateYCheckBox.Text = 'Activate Y';
            app.ActivateZCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateZCheckBox.Text = 'Activate Z';
            app.ActivateThetaCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateThetaCheckBox.Text = 'Activate Theta';
            app.ActivatePhiCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivatePhiCheckBox.Text = 'Activate Phi';
            app.ActivatePsiCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivatePsiCheckBox.Text = 'Activate Psi';
            app.NotePleaseprovideposeintheZYZschemeLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.NotePleaseprovideposeintheZYZschemeLabel.Text = 'Note: Please provide pose in the ZYZ scheme';
            app.GenerateJointDyanamicsButton = uibutton(app.JointDynamicsTab, 'push');
            app.GenerateJointDyanamicsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateJointDyanamicsButtonPushed, true);
            app.GenerateJointDyanamicsButton.Text = 'Generate Joint Dyanamics';
            app.DynamicsGeneratorErrorLable = uilabel(app.JointDynamicsTab);
            app.DynamicsGeneratorErrorLable.Text = '';
            app.TrajectoryErrorLable = uilabel(app.JointDynamicsTab);
            app.TrajectoryErrorLable.Text = '';
            app.ForceControlTab = uitab(app.TabGroup);
            app.ForceControlTab.Title = 'Force Control';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Hyperlink = uihyperlink(app.HelpTab);
            app.Hyperlink.Text = 'Link to Documentation and How to';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticsAppGUIV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end